classdef MotorConnection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConnectionStatusEditField       matlab.ui.control.EditField
        ConnectionStatusEditFieldLabel  matlab.ui.control.Label
        ConnecttoStimulatorButton       matlab.ui.control.Button
        UnlockCodeEditField             matlab.ui.control.EditField
        UnlockCodeEditFieldLabel        matlab.ui.control.Label
        ConnectionPortEditField         matlab.ui.control.EditField
        ConnectionPortEditFieldLabel    matlab.ui.control.Label
        StimulatorTypeDropDown          matlab.ui.control.DropDown
        StimulatorTypeDropDownLabel     matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        ConnecttoTMSStimulatorLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)
        ConnectionPortApp
        StimTypeApp
        UnlockCodeApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Unlock code is nothing is written
            app.UnlockCodeApp = 1;

            % Starting COM port
            app.ConnectionPortApp = 'COM1';
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open Home Page
            TMS_StartUpPage

            % Close app
            delete(app)
        end

        % Value changed function: StimulatorTypeDropDown
        function StimulatorTypeDropDownValueChanged(app, event)
            % If rapid is chosen, the app asks for an unlock code
            if app.StimulatorTypeDropDown.Value == 3
                app.UnlockCodeEditField.Visible = 'on';
                app.UnlockCodeEditFieldLabel.Visible = 'on';
            else
                app.UnlockCodeEditField.Visible = 'off';
                app.UnlockCodeEditFieldLabel.Visible = 'off';
            end

            % StimType is added to workspace when changed
            assignin('base','StimType',app.StimulatorTypeDropDown.Value)
            app.StimTypeApp = app.StimulatorTypeDropDown.Value;
        end

        % Value changed function: ConnectionPortEditField
        function ConnectionPortEditFieldValueChanged(app, event)
            % Update global variable
            app.ConnectionPortApp = app.ConnectionPortEditField.Value;
            
        end

        % Value changed function: UnlockCodeEditField
        function UnlockCodeEditFieldValueChanged(app, event)
            % Update global variable
            app.UnlockCodeApp = app.UnlockCodeEditField.Value;

        end

        % Button pushed function: ConnecttoStimulatorButton
        function ConnecttoStimulatorButtonPushed(app, event)
            % Variables needed to run function
            ConnectionPort = app.ConnectionPortApp;
            StimType = app.StimTypeApp;
            UnlockCode = app.UnlockCodeApp;

            % Run connection function
            [ConnectionSuccess, magstimObject] = TMSStimulatorConnection(ConnectionPort,StimType,UnlockCode);

            % Assigns magstimObject to workspace
            assignin('base','magstimObject',magstimObject)

            % Updates the status depending on connection success or not
            if ConnectionSuccess == 1
                app.ConnectionStatusEditField.Value = 'Connected';
                pause(1.5)
                delete(app)
                SetStimulatorIntensity
            else
                app.ConnectionStatusEditField.Value = 'Could Not Connect';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnecttoTMSStimulatorLabel = uilabel(app.UIFigure);
            app.ConnecttoTMSStimulatorLabel.Text = 'Connect to TMS Stimulator';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.StimulatorTypeDropDownLabel = uilabel(app.UIFigure);
            app.StimulatorTypeDropDownLabel.Text = 'Stimulator Type';
            app.StimulatorTypeDropDown = uidropdown(app.UIFigure);
            app.StimulatorTypeDropDown.Items = {'Magstim', 'Bistim', 'Rapid', 'MagVenture'};
            app.StimulatorTypeDropDown.ItemsData = [1 2 3 4];
            app.StimulatorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulatorTypeDropDownValueChanged, true);
            app.StimulatorTypeDropDown.Value = 1;
            app.ConnectionPortEditFieldLabel = uilabel(app.UIFigure);
            app.ConnectionPortEditFieldLabel.Text = 'Connection Port';
            app.ConnectionPortEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectionPortEditField.ValueChangedFcn = createCallbackFcn(app, @ConnectionPortEditFieldValueChanged, true);
            app.ConnectionPortEditField.Value = 'COM1';
            app.UnlockCodeEditFieldLabel = uilabel(app.UIFigure);
            app.UnlockCodeEditFieldLabel.Visible = 'off';
            app.UnlockCodeEditFieldLabel.Text = 'Unlock Code';
            app.UnlockCodeEditField = uieditfield(app.UIFigure, 'text');
            app.UnlockCodeEditField.ValueChangedFcn = createCallbackFcn(app, @UnlockCodeEditFieldValueChanged, true);
            app.UnlockCodeEditField.Visible = 'off';
            app.ConnecttoStimulatorButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoStimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoStimulatorButtonPushed, true);
            app.ConnecttoStimulatorButton.Text = 'Connect to Stimulator';
            app.ConnectionStatusEditFieldLabel = uilabel(app.UIFigure);
            app.ConnectionStatusEditFieldLabel.Text = 'Connection Status';
            app.ConnectionStatusEditField = uieditfield(app.UIFigure, 'text');
            app.ConnectionStatusEditField.Editable = 'off';
            app.ConnectionStatusEditField.Value = 'Not Connected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorConnection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end