classdef SetStimulatorIntensity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusEditField               matlab.ui.control.EditField
        StatusEditFieldLabel          matlab.ui.control.Label
        ContinuetoProtocolButton      matlab.ui.control.Button
        CoilTemperatureGauge          matlab.ui.control.SemicircularGauge
        CoilTemperatureGaugeLabel     matlab.ui.control.Label
        NonButton                     matlab.ui.control.Button
        DidanMEPoccurLabel            matlab.ui.control.Label
        YesyButton                    matlab.ui.control.Button
        NumberofPulsesatCurrentIntensitySpinner  matlab.ui.control.Spinner
        NumberofPulsesatCurrentIntensitySpinnerLabel  matlab.ui.control.Label
        NumberofMEPsSpinner           matlab.ui.control.Spinner
        NumberofMEPsSpinnerLabel      matlab.ui.control.Label
        FirespaceButton               matlab.ui.control.Button
        ArmButton                     matlab.ui.control.Button
        DetermineMotorThresholdLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        StimulatorOutputSpinner       matlab.ui.control.Spinner
        StimulatorOutputSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)
        Button % Arm or fire button

        magstimObject
        MotorThreshold
        StimType
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Gets magstimObject from workspace
            app.magstimObject = evalin('base','magstimObject');
            app.StimType = evalin('base','StimType');

            % Sets initial values upon start-up
            app.MotorThreshold = 0;
        end

        % Value changed function: StimulatorOutputSpinner
        function StimulatorOutputSpinnerValueChanged(app, event)
            % If a new stimulator output is chosen, it resets the MEP
            % spinner and pulse counter spinner
            app.NumberofPulsesatCurrentIntensitySpinner.Value = 0;
            app.NumberofMEPsSpinner.Value = 0;

            % Updates the motor threshold value used throughout the
            % protocol
            app.MotorThreshold = app.StimulatorOutputSpinner.Value;
        end

        % Button pushed function: FirespaceButton
        function FirespaceButtonPushed(app, event)
            % Update stimulator output value
            Threshold = app.StimulatorOutputSpinner.Value; % Stimulator output value
            app.magstimObject.setAmplitudeA(Threshold);
            
            % Update pulse counter
            app.NumberofPulsesatCurrentIntensitySpinner.Value = app.NumberofPulsesatCurrentIntensitySpinner.Value + 1;

            % Firing
            disp('Firing')
            app.StatusEditField.Value = 'Firing'; % Update the status field

            app.magstimObject.poke();
            app.magstimObject.fire();
            app.magstimObject.pause(1);

            disp('Fired')
            app.StatusEditField.Value = 'Fired'; % Update the status field

            % Check and update temperature
            [Temp1, Temp2] = app.magstimObject.getTemperature();
            Temperature = round(mean([Temp2.CoilTemp1,Temp2.CoilTemp2]));
            app.CoilTemperatureGauge.Value = Temperature;
            
        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
            disp('Arming')
            app.StatusEditField.Value = 'Arming'; % Update status 
            Threshold = app.StimulatorOutputSpinner.Value; % Stimulator output value

            if app.StimType == 2 % bistim
                app.magstimObject.setAmplitudeB(0);
            end

            app.magstimObject.setAmplitudeA(0);
            app.magstimObject.pause(1);
            app.magstimObject.arm();
            app.magstimObject.pause(1);
            app.magstimObject.setAmplitudeA(Threshold);
            
            disp('Armed')
            app.StatusEditField.Value = 'Armed'; % Update status

            % Check and update temperature
            [Temp1, Temp2] = app.magstimObject.getTemperature();
            Temperature = round(mean([Temp2.CoilTemp1,Temp2.CoilTemp2]));
            app.CoilTemperatureGauge.Value = Temperature;

            % Show fire button instead of arm button
            app.ArmButton.Visible = 'off';
            app.FirespaceButton.Visible = 'on';
        end

        % Button pushed function: YesyButton
        function YesyButtonPushed(app, event)
            % Update MEP spinner number 
            app.NumberofMEPsSpinner.Value = app.NumberofMEPsSpinner.Value + 1;            
        end

        % Button pushed function: ContinuetoProtocolButton
        function ContinuetoProtocolButtonPushed(app, event)
            % Update motor threshold in workspace
            assignin('base','MotorThreshold',app.MotorThreshold);

            % Open PID_Page
            PID_Page

            % Close app
            delete(app)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open home page
            TMS_StartUpPage

            % Disconnect magstim 
            app.magstimObject.disconnect();

            % Close app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disconnect magstim - comment this out if you want to open and
            % close the app when it is not connected
            app.magstimObject.disconnect();

            % Close app
            delete(app)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if strcmp(event.Key, 'y')
                YesyButtonPushed(app)
                disp('MEP observed')
            elseif strcmp(event.Key, 'n')
                disp('MEP not observed')
            elseif strcmp(event.Key, 'space')
                FirespaceButtonPushed(app)                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.StimulatorOutputSpinnerLabel = uilabel(app.UIFigure);
            app.StimulatorOutputSpinnerLabel.Text = 'Stimulator Output';
            app.StimulatorOutputSpinner = uispinner(app.UIFigure);
            app.StimulatorOutputSpinner.Limits = [0 100];
            app.StimulatorOutputSpinner.RoundFractionalValues = 'on';
            app.StimulatorOutputSpinner.ValueChangedFcn = createCallbackFcn(app, @StimulatorOutputSpinnerValueChanged, true);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.DetermineMotorThresholdLabel = uilabel(app.UIFigure);
            app.DetermineMotorThresholdLabel.Text = 'Determine Motor Threshold';
            app.ArmButton = uibutton(app.UIFigure, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            app.FirespaceButton = uibutton(app.UIFigure, 'push');
            app.FirespaceButton.ButtonPushedFcn = createCallbackFcn(app, @FirespaceButtonPushed, true);
            app.FirespaceButton.Visible = 'off';
            app.FirespaceButton.Text = 'Fire (space)';
            app.NumberofMEPsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofMEPsSpinnerLabel.Text = 'Number of MEPs';
            app.NumberofMEPsSpinner = uispinner(app.UIFigure);
            app.NumberofMEPsSpinner.Limits = [0 Inf];
            app.NumberofMEPsSpinner.RoundFractionalValues = 'on';
            app.NumberofPulsesatCurrentIntensitySpinnerLabel = uilabel(app.UIFigure);
            app.NumberofPulsesatCurrentIntensitySpinnerLabel.Text = 'Number of Pulses at Current Intensity';
            app.NumberofPulsesatCurrentIntensitySpinner = uispinner(app.UIFigure);
            app.NumberofPulsesatCurrentIntensitySpinner.Limits = [0 Inf];
            app.NumberofPulsesatCurrentIntensitySpinner.RoundFractionalValues = 'on';
            app.YesyButton = uibutton(app.UIFigure, 'push');
            app.YesyButton.ButtonPushedFcn = createCallbackFcn(app, @YesyButtonPushed, true);
            app.YesyButton.Text = 'Yes (y)';
            app.DidanMEPoccurLabel = uilabel(app.UIFigure);
            app.DidanMEPoccurLabel.Text = 'Did an MEP occur? ';
            app.NonButton = uibutton(app.UIFigure, 'push');
            app.NonButton.Text = 'No (n)';
            app.CoilTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.CoilTemperatureGaugeLabel.Text = 'Coil Temperature';
            app.CoilTemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.CoilTemperatureGauge.Limits = [0 50];
            app.ContinuetoProtocolButton = uibutton(app.UIFigure, 'push');
            app.ContinuetoProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetoProtocolButtonPushed, true);
            app.ContinuetoProtocolButton.Text = 'Continue to Protocol';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetStimulatorIntensity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end